classdef elevatorSimulationControl_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ElevatorControlSystemSimulationControlLabel  matlab.ui.control.Label
        SimulationParametersPanel       matlab.ui.container.Panel
        PlatformMassM1kgEditFieldLabel  matlab.ui.control.Label
        PlatformMassM1kgEditField       matlab.ui.control.NumericEditField
        CageMassM2kgEditFieldLabel      matlab.ui.control.Label
        CageMassM2kgEditField           matlab.ui.control.NumericEditField
        PassengerMassmkgEditFieldLabel  matlab.ui.control.Label
        PassengerMassmkgEditField       matlab.ui.control.NumericEditField
        SpringCoefficientKNmEditFieldLabel  matlab.ui.control.Label
        SpringCoefficientKNmEditField   matlab.ui.control.NumericEditField
        DamperCoefficientCNsmEditFieldLabel  matlab.ui.control.Label
        DamperCoefficientCNsmEditField  matlab.ui.control.NumericEditField
        FrictionBNsmEditFieldLabel      matlab.ui.control.Label
        FrictionBNsmEditField           matlab.ui.control.NumericEditField
        RunSimulationButton             matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.PlatformMassM1kgEditField.Value
            app.CageMassM2kgEditField.Value
            app.PassengerMassmkgEditField.Value
            app.SpringCoefficientKNmEditField.Value
            app.DamperCoefficientCNsmEditField.Value
            app.FrictionBNsmEditField.Value
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ElevatorControlSystemSimulationControlLabel = uilabel(app.UIFigure);
            app.ElevatorControlSystemSimulationControlLabel.Text = 'Elevator Control System Simulation Control';
            app.SimulationParametersPanel = uipanel(app.UIFigure);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.PlatformMassM1kgEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.PlatformMassM1kgEditFieldLabel.Text = 'Platform Mass [M1-kg]';
            app.PlatformMassM1kgEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PlatformMassM1kgEditField.Limits = [0 Inf];
            app.PlatformMassM1kgEditField.Value = 100;
            app.CageMassM2kgEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.CageMassM2kgEditFieldLabel.Text = 'Cage Mass [M2-kg]';
            app.CageMassM2kgEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.CageMassM2kgEditField.Limits = [0 Inf];
            app.CageMassM2kgEditField.Value = 150;
            app.PassengerMassmkgEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.PassengerMassmkgEditFieldLabel.Text = 'Passenger Mass[m-kg]';
            app.PassengerMassmkgEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PassengerMassmkgEditField.Limits = [0 220];
            app.PassengerMassmkgEditField.Value = 220;
            app.SpringCoefficientKNmEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.SpringCoefficientKNmEditFieldLabel.Text = 'Spring Coefficient [K-N/m]';
            app.SpringCoefficientKNmEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.SpringCoefficientKNmEditField.Limits = [0 Inf];
            app.SpringCoefficientKNmEditField.ValueDisplayFormat = '%.0f';
            app.SpringCoefficientKNmEditField.Value = 30000;
            app.DamperCoefficientCNsmEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.DamperCoefficientCNsmEditFieldLabel.Text = 'Damper Coefficient [C-N*s/m]';
            app.DamperCoefficientCNsmEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.DamperCoefficientCNsmEditField.Limits = [0 Inf];
            app.DamperCoefficientCNsmEditField.Value = 30;
            app.FrictionBNsmEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.FrictionBNsmEditFieldLabel.Text = 'Friction [B-N*s/m]';
            app.FrictionBNsmEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.FrictionBNsmEditField.Limits = [0 Inf];
            app.FrictionBNsmEditField.Value = 60;
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = elevatorSimulationControl_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end